classdef vsc_matlab_project < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        AutomaticsaveSwitch           matlab.ui.control.Switch
        AutomaticsaveSwitchLabel      matlab.ui.control.Label
        CreatenetLabel                matlab.ui.control.Label
        TrainanddrawButton            matlab.ui.control.Button
        neuronsEditField_3            matlab.ui.control.NumericEditField
        neuronsEditField_3Label       matlab.ui.control.Label
        neuronsEditField_2            matlab.ui.control.NumericEditField
        neuronsEditField_2Label       matlab.ui.control.Label
        neuronsEditField              matlab.ui.control.NumericEditField
        neuronsEditFieldLabel         matlab.ui.control.Label
        NuminputpointsEditField       matlab.ui.control.NumericEditField
        NuminputpointsEditFieldLabel  matlab.ui.control.Label
        NumofepochsEditField          matlab.ui.control.NumericEditField
        NumofepochsEditFieldLabel     matlab.ui.control.Label
        LAYER3transfncDropDown        matlab.ui.control.DropDown
        LAYER3transfncDropDownLabel   matlab.ui.control.Label
        LAYER2transfncDropDown        matlab.ui.control.DropDown
        LAYER2transfncDropDownLabel   matlab.ui.control.Label
        LAYER1transfncDropDown        matlab.ui.control.DropDown
        LAYER1transfncDropDownLabel   matlab.ui.control.Label
        ShubertfunctionLabel          matlab.ui.control.Label
        PretrainedmodelLabel          matlab.ui.control.Label
        DrawButton                    matlab.ui.control.Button
        Net_axes                      matlab.ui.control.UIAxes
        Pretrained_axes               matlab.ui.control.UIAxes
        OG_axes                       matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
            function z = shubert(app,x1, x2)
                sum1 = 0;
                sum2 = 0;
                for i = 1:5
                    sum1 = sum1 + i * cos((i + 1) * x1 + i);
                    sum2 = sum2 + i * cos((i + 1) * x2 + i);
                end
                z = sum1 * sum2;
            end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function plot_og(app)
            % [-4,4]
            x1 = -4:0.1:4;
            x2 = -4:0.1:4;
    
            [X1, X2] = meshgrid(x1, x2);
    
            % Init Z
            Z = zeros(size(X1));
    
            % Calculate Z
            for i = 1:numel(X1)
                Z(i) = shubert(app,X1(i), X2(i));
            end
    
            % Plot the OG Shubert
            surf(app.OG_axes, X1, X2, Z)
            xlabel(app.OG_axes, 'x1');
            ylabel(app.OG_axes, 'x2');
            zlabel(app.OG_axes, 'f(x, y)');
            title(app.OG_axes, 'Shubert Function');
        end

        % Button pushed function: DrawButton
        function trained_plot(app, event)
            load('trained_network.mat');
            
            % [-4,4]
            x1 = -4:0.1:4;
            x2 = -4:0.1:4;
            
            [X1, X2] = meshgrid(x1, x2);
            
            % Use the trained neural network to approximate the Shubert function
            Z_approx = net([X1(:)'; X2(:)']);
            
            % Reshape
            Z_approx = reshape(Z_approx, size(X1));
            
            % Plot approximated Shubert function
            surf(app.Pretrained_axes,X1, X2, Z_approx)
            xlabel(app.Pretrained_axes,'x1');
            ylabel(app.Pretrained_axes,'x2');
            zlabel(app.Pretrained_axes,'f(x, y)');
            title(app.Pretrained_axes,'Approximated Shubert Function');
        end

        % Button pushed function: TrainanddrawButton
        function create_net(app, event)
            % [-4,4]
            x1 = -4:0.1:4;
            x2 = -4:0.1:4;
    
            [X1, X2] = meshgrid(x1, x2);
            
            % neurons
            layer_1_neurons = app.neuronsEditField.Value;
            layer_2_neurons = app.neuronsEditField_2.Value;
            layer_3_neurons = app.neuronsEditField_3.Value;
            
            % create net
            my_net = feedforwardnet([layer_1_neurons, layer_2_neurons, layer_3_neurons]);
            
            % functions
            my_net.layers{1}.transferFcn = app.LAYER1transfncDropDown.Value;
            my_net.layers{2}.transferFcn = app.LAYER2transfncDropDown.Value;
            my_net.layers{3}.transferFcn = app.LAYER3transfncDropDown.Value;

            % gen points
            points = app.NuminputpointsEditField.Value;
            x1_train = -4 + 8 * rand(points, 1);
            x2_train = -4 + 8 * rand(points, 1);
            %Z_train = arrayfun(@shubert, x1_train, x2_train);
            Z_train = arrayfun(@(x1, x2) app.shubert(x1, x2), x1_train, x2_train);

            % set epochs
            my_net.trainParam.epochs = app.NumofepochsEditField.Value;

            % train
            my_net = train(my_net,[x1_train'; x2_train'], Z_train');

            % use
            Z_approx = my_net([X1(:)'; X2(:)']);
            
            % reshape
            Z_approx = reshape(Z_approx, size(X1));

            % plot
            surf(app.Net_axes,X1, X2, Z_approx)
            xlabel(app.Net_axes,'x1');
            ylabel(app.Net_axes,'x2');
            zlabel(app.Net_axes,'f(x, y)');
            title(app.Net_axes,'Approximated Shubert Function');
            
            % Save the trained network
            if strcmp(app.AutomaticsaveSwitch.Value, 'On')
                save('my_trained_net.mat', 'my_net');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.OG_axes = uiaxes(app.UIFigure);
            title(app.OG_axes, 'Shubert Function')
            xlabel(app.OG_axes, 'X')
            ylabel(app.OG_axes, 'Y')
            zlabel(app.OG_axes, 'Z')
            app.Pretrained_axes = uiaxes(app.UIFigure);
            title(app.Pretrained_axes, 'Approximated Shubert Function')
            xlabel(app.Pretrained_axes, 'X')
            ylabel(app.Pretrained_axes, 'Y')
            zlabel(app.Pretrained_axes, 'Z')
            app.Net_axes = uiaxes(app.UIFigure);
            title(app.Net_axes, 'Approximated Shubert Function')
            xlabel(app.Net_axes, 'X')
            ylabel(app.Net_axes, 'Y')
            zlabel(app.Net_axes, 'Z')
            app.DrawButton = uibutton(app.UIFigure, 'push');
            app.DrawButton.ButtonPushedFcn = createCallbackFcn(app, @trained_plot, true);
            app.DrawButton.Text = 'Draw';
            app.PretrainedmodelLabel = uilabel(app.UIFigure);
            app.PretrainedmodelLabel.Text = 'Pretrained model';
            app.ShubertfunctionLabel = uilabel(app.UIFigure);
            app.ShubertfunctionLabel.Text = 'Shubert function';
            app.LAYER1transfncDropDownLabel = uilabel(app.UIFigure);
            app.LAYER1transfncDropDownLabel.Text = 'LAYER1 trans.fnc';
            app.LAYER1transfncDropDown = uidropdown(app.UIFigure);
            app.LAYER1transfncDropDown.Items = {'compet', 'elliotsig', 'hardlim', 'hardlims', 'logsig', 'netinv', 'poslin', 'purelin', 'radbas', 'radbasn', 'satlin', 'satlins', 'softmax', 'tansig', 'tribas'};
            app.LAYER1transfncDropDown.Value = 'tansig';
            app.LAYER2transfncDropDownLabel = uilabel(app.UIFigure);
            app.LAYER2transfncDropDownLabel.Text = 'LAYER2 trans.fnc';
            app.LAYER2transfncDropDown = uidropdown(app.UIFigure);
            app.LAYER2transfncDropDown.Items = {'compet', 'elliotsig', 'hardlim', 'hardlims', 'logsig', 'netinv', 'poslin', 'purelin', 'radbas', 'radbasn', 'satlin', 'satlins', 'softmax', 'tansig', 'tribas'};
            app.LAYER2transfncDropDown.Value = 'tansig';
            app.LAYER3transfncDropDownLabel = uilabel(app.UIFigure);
            app.LAYER3transfncDropDownLabel.Text = 'LAYER3 trans.fnc';
            app.LAYER3transfncDropDown = uidropdown(app.UIFigure);
            app.LAYER3transfncDropDown.Items = {'compet', 'elliotsig', 'hardlim', 'hardlims', 'logsig', 'netinv', 'poslin', 'purelin', 'radbas', 'radbasn', 'satlin', 'satlins', 'softmax', 'tansig', 'tribas'};
            app.LAYER3transfncDropDown.Value = 'tansig';
            app.NumofepochsEditFieldLabel = uilabel(app.UIFigure);
            app.NumofepochsEditFieldLabel.Text = 'Num. of epochs';
            app.NumofepochsEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumofepochsEditField.Value = 200;
            app.NuminputpointsEditFieldLabel = uilabel(app.UIFigure);
            app.NuminputpointsEditFieldLabel.Text = 'Num. input points';
            app.NuminputpointsEditField = uieditfield(app.UIFigure, 'numeric');
            app.NuminputpointsEditField.ValueDisplayFormat = '%.0f';
            app.NuminputpointsEditField.Value = 10000;
            app.neuronsEditFieldLabel = uilabel(app.UIFigure);
            app.neuronsEditFieldLabel.Text = 'neurons';
            app.neuronsEditField = uieditfield(app.UIFigure, 'numeric');
            app.neuronsEditField.Value = 20;
            app.neuronsEditField_2Label = uilabel(app.UIFigure);
            app.neuronsEditField_2Label.Text = 'neurons';
            app.neuronsEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.neuronsEditField_2.Value = 10;
            app.neuronsEditField_3Label = uilabel(app.UIFigure);
            app.neuronsEditField_3Label.Text = 'neurons';
            app.neuronsEditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.neuronsEditField_3.Value = 5;
            app.TrainanddrawButton = uibutton(app.UIFigure, 'push');
            app.TrainanddrawButton.ButtonPushedFcn = createCallbackFcn(app, @create_net, true);
            app.TrainanddrawButton.Text = 'Train and draw';
            app.CreatenetLabel = uilabel(app.UIFigure);
            app.CreatenetLabel.Text = 'Create net';
            app.AutomaticsaveSwitchLabel = uilabel(app.UIFigure);
            app.AutomaticsaveSwitchLabel.Text = 'Automatic save';
            app.AutomaticsaveSwitch = uiswitch(app.UIFigure, 'slider');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = vsc_matlab_project

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @plot_og)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end